classdef IDE_impedance_vs_freq_response_2_0 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure                   % UI Figure
        LabelNumericEditField2  matlab.ui.control.Label            % Digit ...
        L                       matlab.ui.control.NumericEditField % [-Inf ...
        LabelNumericEditField3  matlab.ui.control.Label            % Digit ...
        N                       matlab.ui.control.NumericEditField % [-Inf ...
        LabelNumericEditField4  matlab.ui.control.Label            % Sepera...
        S                       matlab.ui.control.NumericEditField % [-Inf ...
        LabelNumericEditField5  matlab.ui.control.Label            % Conduc...
        SigmaSol                matlab.ui.control.NumericEditField % [-Inf ...
        LabelNumericEditField6  matlab.ui.control.Label            % Relati...
        Er                      matlab.ui.control.NumericEditField % [-Inf ...
        LabelNumericEditField7  matlab.ui.control.Label            % Wire r...
        Rlead                   matlab.ui.control.NumericEditField % [-Inf ...
        LabelNumericEditField8  matlab.ui.control.Label            % Double...
        CdlSurf                 matlab.ui.control.NumericEditField % [-Inf ...
        LabelNumericEditField9  matlab.ui.control.Label            % Start ...
        Start                   matlab.ui.control.NumericEditField % [-Inf ...
        LabelNumericEditField10 matlab.ui.control.Label            % Step F...
        Step                    matlab.ui.control.NumericEditField % [-Inf ...
        LabelNumericEditField11 matlab.ui.control.Label            % Finish...
        Finish                  matlab.ui.control.NumericEditField % [-Inf ...
        Calculate               matlab.ui.control.Button           % Plot
        LabelNumericEditField   matlab.ui.control.Label            % Digit ...
        W                       matlab.ui.control.NumericEditField % [-Inf ...
        TabGroup                matlab.ui.container.TabGroup       % Impeda...
        Tab                     matlab.ui.container.Tab            % Impeda...
        UIAxes                  matlab.ui.control.UIAxes           % Impeda...
        Tab2                    matlab.ui.container.Tab            % Phase
        UIAxes2                 matlab.ui.control.UIAxes           % Phase ...
    end


    methods (Access = private)

    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Calculate button pushed function
        function CalculateButtonPushed(app)
            
 k=cos((pi/2)*(app.W.Value/(app.S.Value+app.W.Value)));
 a=k;
 b=sqrt(1-(k^2));
 Ka=@(t) 1./sqrt((1-t.^2).*(1-(a.^2.*t.^2)));
 Kb=@(t) 1./sqrt((1-t.^2).*(1-(b.^2.*t.^2)));
 Kfa=integral(Ka,0,1);
 Kfb=integral(Kb,0,1);
 Kcell=(2/((app.N.Value-1)*app.L.Value))*(Kfa/Kfb);         %Kcell
 Rsol=Kcell/app.SigmaSol.Value;           %Rsol
 Ccell=(8.854e-12*app.Er.Value)/Kcell;           %Ccell
 Cdl=0.5*app.W.Value*app.L.Value*app.N.Value*app.CdlSurf.Value;           %Cdl
 
 freq=(app.Start.Value:app.Step.Value:app.Finish.Value);
 omega=2*pi.*freq;
 Z1=Rsol+(2./(1j.*omega.*Cdl));
 Z=(2*app.Rlead.Value)+(Z1./((1j.*omega.*Ccell.*Z1)+1));
 
 
 plot(app.UIAxes,log10(freq),log10(abs(Z)));
 

 plot(app.UIAxes2,log10(freq),angle(Z)*180/pi)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.LabelNumericEditField2 = uilabel(app.UIFigure);
            app.LabelNumericEditField2.Text = 'Digit Length(m)[L]';
            app.L = uieditfield(app.UIFigure, 'numeric');
            app.LabelNumericEditField3 = uilabel(app.UIFigure);
            app.LabelNumericEditField3.Text = 'Digit Number[N]';
            app.N = uieditfield(app.UIFigure, 'numeric');
            app.LabelNumericEditField4 = uilabel(app.UIFigure);
            app.LabelNumericEditField4.Text = 'Seperation between Digits(m)[S]';
            app.S = uieditfield(app.UIFigure, 'numeric');
            app.LabelNumericEditField5 = uilabel(app.UIFigure);
            app.LabelNumericEditField5.Text = 'Conductivity of Solution(S/m)[σsol]';
            app.SigmaSol = uieditfield(app.UIFigure, 'numeric');
            app.LabelNumericEditField6 = uilabel(app.UIFigure);
            app.LabelNumericEditField6.Text = 'Relative permittivity of Solution[εr]';
            app.Er = uieditfield(app.UIFigure, 'numeric');
            app.LabelNumericEditField7 = uilabel(app.UIFigure);
            app.LabelNumericEditField7.Text = 'Wire resistance(Ω)[Rlead]';
            app.Rlead = uieditfield(app.UIFigure, 'numeric');
            app.LabelNumericEditField8 = uilabel(app.UIFigure);
            app.LabelNumericEditField8.Text = 'Double Layer Capacitance Ch.(F/m2)[Cdlsurface]';
            app.CdlSurf = uieditfield(app.UIFigure, 'numeric');
            app.LabelNumericEditField9 = uilabel(app.UIFigure);
            app.LabelNumericEditField9.Text = 'Start Frequency(Hz)';
            app.Start = uieditfield(app.UIFigure, 'numeric');
            app.LabelNumericEditField10 = uilabel(app.UIFigure);
            app.LabelNumericEditField10.Text = 'Step Frequency(Hz)';
            app.Step = uieditfield(app.UIFigure, 'numeric');
            app.LabelNumericEditField11 = uilabel(app.UIFigure);
            app.LabelNumericEditField11.Text = 'Finish Frequency(Hz)';
            app.Finish = uieditfield(app.UIFigure, 'numeric');
            app.Calculate = uibutton(app.UIFigure, 'push');
            app.Calculate.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed);
            app.Calculate.Text = 'Plot';
            app.LabelNumericEditField = uilabel(app.UIFigure);
            app.LabelNumericEditField.Text = 'Digit Width(m)[W]';
            app.W = uieditfield(app.UIFigure, 'numeric');
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.Units = 'pixels';
            app.Tab = uitab(app.TabGroup);
            app.Tab.Units = 'pixels';
            app.Tab.Title = 'Impedance ';
            app.UIAxes = uiaxes(app.Tab);
            title(app.UIAxes, 'Impedance vs Frequency');
            xlabel(app.UIAxes, 'Frequency (Hz) (log scale)');
            ylabel(app.UIAxes, '|Z|, \Omega (log scale)');
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Units = 'pixels';
            app.Tab2.Title = 'Phase';
            app.UIAxes2 = uiaxes(app.Tab2);
            title(app.UIAxes2, 'Phase vs Frequency');
            xlabel(app.UIAxes2, 'Frequency (Hz) (log scale)');
            ylabel(app.UIAxes2, 'Phase (deg)');
        end
    end

    methods (Access = public)

        % Construct app
        function app = IDE_impedance_vs_freq_response_2_0()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end